classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        servo     matlab.ui.container.ButtonGroup
        s1        matlab.ui.control.RadioButton
        s2        matlab.ui.control.RadioButton
        s3        matlab.ui.control.RadioButton
        pos1      matlab.ui.control.Button
        pos2      matlab.ui.control.Button
        pos3      matlab.ui.control.Button
        pos4      matlab.ui.control.Button
        pos5      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Callback function
        function textoValueChanged(app, event)
            
        end

        % Button pushed function: pos1
        function pos1ButtonPushed(app, event)
                       oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales
            dato='1';
            fwrite(s,dato,'uchar')
            fclose(s);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            fclose(s);
        end

        % Button pushed function: pos2
        function pos2ButtonPushed(app, event)
                        oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales

            dato='2';
            fwrite(s,dato,'uchar');
            fclose(s);
        end

        % Button pushed function: pos3
        function pos3ButtonPushed(app, event)
                        oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales

            dato='3';
            fwrite(s,dato,'uchar');
            fclose(s);
        end

        % Button pushed function: pos4
        function pos4ButtonPushed(app, event)
                       oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales

            dato='4';
            fwrite(s,dato,'uchar');
            fclose(s);
        end

        % Button pushed function: pos5
        function pos5ButtonPushed(app, event)
                        oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales
            dato='5';
            fwrite(s,dato,'uchar');
            fclose(s);
        end

        % Selection changed function: servo
        function servoSelectionChanged(app, event)
                        oldobj = instrfind;
if~isempty(oldobj)
    fclose(oldobj);
    delete(oldobj);
end
%crea puerto serial
%s =serial('COM2','BaudRate',9600,'Databits',8,'Parity','None,'StopBits',1);
if ~exist('s','var')
   s=serial('COM24','BaudRate',9600,'Databits',8,'Parity','None','StopBits',1);
end

%apertura de comunicacion serial
if strcmp(get(s,'status'),'closed')
    fopen(s);
end
tic
%apaga los datos iniciales
            selectedButton = app.servo.SelectedObject;
            dato='s';
            fwrite(s,dato,'uchar');
            pause(0.2);
            if selectedButton == app.s1
                dato='0';
            elseif selectedButton == app.s2
                dato='1';
            elseif selectedButton == app.s3
                dato='2';
            end
            fwrite(s,dato,'uchar')
            fclose(s);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.servo = uibuttongroup(app.UIFigure);
            app.servo.SelectionChangedFcn = createCallbackFcn(app, @servoSelectionChanged, true);
            app.servo.Title = 'seleccionar servo';
            app.s1 = uiradiobutton(app.servo);
            app.s1.Text = '1';
            app.s1.Value = true;
            app.s2 = uiradiobutton(app.servo);
            app.s2.Text = '2';
            app.s3 = uiradiobutton(app.servo);
            app.s3.Text = '3';
            app.pos1 = uibutton(app.UIFigure, 'push');
            app.pos1.ButtonPushedFcn = createCallbackFcn(app, @pos1ButtonPushed, true);
            app.pos1.Text = 'poscicion 1';
            app.pos2 = uibutton(app.UIFigure, 'push');
            app.pos2.ButtonPushedFcn = createCallbackFcn(app, @pos2ButtonPushed, true);
            app.pos2.Text = 'poscicion 2';
            app.pos3 = uibutton(app.UIFigure, 'push');
            app.pos3.ButtonPushedFcn = createCallbackFcn(app, @pos3ButtonPushed, true);
            app.pos3.Text = 'poscicion 3';
            app.pos4 = uibutton(app.UIFigure, 'push');
            app.pos4.ButtonPushedFcn = createCallbackFcn(app, @pos4ButtonPushed, true);
            app.pos4.Text = 'poscicion 4';
            app.pos5 = uibutton(app.UIFigure, 'push');
            app.pos5.ButtonPushedFcn = createCallbackFcn(app, @pos5ButtonPushed, true);
            app.pos5.Text = 'poscicion 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end