classdef interfazManipulador_6R < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Slider                matlab.ui.control.Slider
        Slider_2              matlab.ui.control.Slider
        Slider_3              matlab.ui.control.Slider
        Slider_4              matlab.ui.control.Slider
        Slider_5              matlab.ui.control.Slider
        Slider_6              matlab.ui.control.Slider
        servo0EditFieldLabel  matlab.ui.control.Label
        servo0EditField       matlab.ui.control.NumericEditField
        servo1EditFieldLabel  matlab.ui.control.Label
        servo1EditField       matlab.ui.control.NumericEditField
        servo2EditFieldLabel  matlab.ui.control.Label
        servo2EditField       matlab.ui.control.NumericEditField
        servo3EditFieldLabel  matlab.ui.control.Label
        servo3EditField       matlab.ui.control.NumericEditField
        servo4EditFieldLabel  matlab.ui.control.Label
        servo4EditField       matlab.ui.control.NumericEditField
        servo5EditFieldLabel  matlab.ui.control.Label
        servo5EditField       matlab.ui.control.NumericEditField
        figura                matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function envio(app,servo,angulo)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
clc

% th1=str2double(app.Slider)*pi/180;
% th2=str2double(app.Slider_2)*pi/180;
% th3=str2double(app.Slider_3)*pi/180;
% th4=str2double(app.Slider_4)*pi/180;
% th5=str2double(app.Slider_5)*pi/180;
th1=90*pi/180;
th2=-90*pi/180;
th3=90*pi/180;
th4=0*pi/180;
th5=0*pi/180;


L1=1;
L2=20;
L3=20;
L4=20;
L5=20;

L(1)=Link([0 0 L1 -pi/2]);
L(2)=Link([0 0 L2 0]);
L(3)=Link([0 0 L3 pi/2]);
L(4)=Link([-pi/2 0 L4 pi/2]);
L(5)=Link([pi/2 0 L5 0]);

Robot=SerialLink(L,'name','Robot');
q=[th1 th2 th3 th4 th5];
hold(app.figura,"on");
app.figura=Robot.plot(q,'workspace',[-100 100 -100 100 0 100]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %fclose(app.s);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-90 90];
            app.Slider.Value = 90;
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [-90 90];
            app.Slider_2.Value = -90;
            app.Slider_3 = uislider(app.UIFigure);
            app.Slider_3.Limits = [-90 90];
            app.Slider_4 = uislider(app.UIFigure);
            app.Slider_4.Limits = [-90 90];
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_5.Limits = [-90 90];
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_6.Limits = [-90 90];
            app.servo0EditFieldLabel = uilabel(app.UIFigure);
            app.servo0EditFieldLabel.Text = {'servo 0'; ''};
            app.servo0EditField = uieditfield(app.UIFigure, 'numeric');
            app.servo1EditFieldLabel = uilabel(app.UIFigure);
            app.servo1EditFieldLabel.Text = 'servo 1';
            app.servo1EditField = uieditfield(app.UIFigure, 'numeric');
            app.servo2EditFieldLabel = uilabel(app.UIFigure);
            app.servo2EditFieldLabel.Text = 'servo 2';
            app.servo2EditField = uieditfield(app.UIFigure, 'numeric');
            app.servo3EditFieldLabel = uilabel(app.UIFigure);
            app.servo3EditFieldLabel.Text = 'servo 3';
            app.servo3EditField = uieditfield(app.UIFigure, 'numeric');
            app.servo4EditFieldLabel = uilabel(app.UIFigure);
            app.servo4EditFieldLabel.Text = 'servo 4';
            app.servo4EditField = uieditfield(app.UIFigure, 'numeric');
            app.servo5EditFieldLabel = uilabel(app.UIFigure);
            app.servo5EditFieldLabel.Text = 'servo 5';
            app.servo5EditField = uieditfield(app.UIFigure, 'numeric');
            app.figura = uiaxes(app.UIFigure);
            title(app.figura, 'Title')
            xlabel(app.figura, 'X')
            ylabel(app.figura, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazManipulador_6R

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end